classdef mooney_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LocalCheckBox             matlab.ui.control.CheckBox
        OtsusCheckBox             matlab.ui.control.CheckBox
        ChooseoutputfolderButton  matlab.ui.control.Button
        ListBox                   matlab.ui.control.ListBox
        Label                     matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        ChooseinputfolderButton   matlab.ui.control.Button
        ThresholdSlider           matlab.ui.control.Slider
        ThresholdSliderLabel      matlab.ui.control.Label
        BlurlevelSlider           matlab.ui.control.Slider
        BlurlevelSliderLabel      matlab.ui.control.Label
        ThresholdSwitch           matlab.ui.control.Switch
        ThresholdSwitchLabel      matlab.ui.control.Label
        BlurSwitch                matlab.ui.control.Switch
        BlurSwitchLabel           matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        image_original % Description
        image_blur
        image_mooney
        curr_dir
        output_folder
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseinputfolderButton
        function ChooseinputfolderButtonPushed(app, event)
            
            %file = uigetfile({'*.*'},'File Selector');
            
            d = uigetdir(pwd, 'Select a folder');
            cd(d)
            
            files = dir(fullfile(d, '*.jpg'));
            for i=1:1:length(files)
                app.ListBox.Items{i}=[files(i).folder '/' files(i).name];
                app.ListBox.ItemsData{i} = files(i).name;
            end
            % show first image
            %% resize image to 500 x 500 pixels
            test_image = imread(app.ListBox.Items{1});
            % resized_image = imresize(test_image,[500 500]); % Former line to resize images
            resized_image = test_image; 
            %% convert to grayscale
            app.image_original = rgb2gray(resized_image);
            
            imshow(app.image_original, 'Parent',app.UIAxes)
            imshow(app.image_original, 'Parent',app.UIAxes_2)
            
            
        end

        % Value changed function: BlurSwitch
        function BlurSwitchValueChanged(app, event)
            value = string(app.BlurSwitch.Value);
            if value == "Yes"
                app.image_blur = imgaussfilt(app.image_original,app.BlurlevelSlider.Value);
                imshow(app.image_blur, 'Parent',app.UIAxes)
                imshow(app.image_blur, 'Parent',app.UIAxes_2)
            else
                imshow(app.image_original, 'Parent',app.UIAxes)
                imshow(app.image_original, 'Parent',app.UIAxes_2)
            end
                
        end

        % Value changed function: BlurlevelSlider
        function BlurlevelSliderValueChanged(app, event)
            value = app.BlurlevelSlider.Value;
            app.image_blur = imgaussfilt(app.image_original,value);
            if string(app.BlurSwitch.Value) == "Yes"
                imshow(app.image_blur, 'Parent',app.UIAxes)
                imshow(app.image_blur, 'Parent',app.UIAxes_2)
            end
            
        end

        % Value changed function: ThresholdSwitch
        function ThresholdSwitchValueChanged(app, event)
            value = string(app.ThresholdSwitch.Value);
            level = graythresh(app.image_original);
            if value == "Yes"
                app.image_mooney = imbinarize(app.image_blur);
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                app.ThresholdSlider.Value = level;
                app.OtsusCheckBox.Value = 1;
            else
                imshow(app.image_blur, 'Parent',app.UIAxes_2)
            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            
            app.LocalCheckBox.Value = 0;
            app.OtsusCheckBox.Value = 0;
            app.image_mooney = imbinarize(app.image_blur, value);
            imshow(app.image_mooney, 'Parent',app.UIAxes_2)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imwrite(app.image_original,[app.output_folder, '/', app.ListBox.Value(1:end-4), '_gray.jpg']);
            imwrite(app.image_mooney,[app.output_folder, '/', app.ListBox.Value(1:end-4), '_mooney.jpg' ]);
            imwrite(app.image_blur,[app.output_folder, '/', app.ListBox.Value(1:end-4), '_gray_blurr.jpg' ]);
            if app.OtsusCheckBox.Value == 1
                method = 'otsu';
                factor = 'threshold';
            elseif app.LocalCheckBox.Value == 1
                method = 'local';
                factor = 'sensitivity';
            else
                method = 'manual';
                factor = 'threshold';
            end
            metad = table(app.BlurlevelSlider.Value, app.ThresholdSlider.Value, string(method), string(factor), 'VariableNames', { 'blur_sigma', 'th_value', 'th_method','th_factor'});
            writetable(metad, [app.output_folder, '/', app.ListBox.Value(1:end-4) '_metadata.csv']);
            sprintf('Outputs and logs for images %s saved at %s',app.ListBox.Value(1:end-4), app.output_folder)

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % value = app.ListBox.ItemsData;
            % if new image, reset everything
            imshow(app.image_original, 'Parent',app.UIAxes)
            imshow(app.image_original, 'Parent',app.UIAxes_2)
            app.ThresholdSlider.Value = 0;
            %app.ThresholdSwitch.Value = "No";
            app.BlurlevelSlider.Value = 0.1;
            %app.BlurSwitch.Value = "No";
            app.OtsusCheckBox.Value = 0;
            
            %% resize image to 500 x 500 pixels
            test_image = imread(app.ListBox.Value);
            % resized_image = imresize(test_image,[500 500]); % Former line to resize images
            resized_image = test_image; 

            %% convert to grayscale
            app.image_original = rgb2gray(resized_image);
            imwrite(app.image_original,[app.output_folder, '/', app.ListBox.Value(1:end-4), '_gray.jpg']);
            imshow(app.image_original, 'Parent',app.UIAxes)
            imshow(app.image_original, 'Parent',app.UIAxes_2)
        end

        % Button pushed function: ChooseoutputfolderButton
        function ChooseoutputfolderButtonPushed(app, event)
            app.output_folder = uigetdir(pwd, 'Select a folder');
            
            
        end

        % Value changed function: OtsusCheckBox
        function OtsusCheckBoxValueChanged(app, event)
            value = app.OtsusCheckBox.Value;
            if string(app.ThresholdSwitch.Value) == "Yes" && value == 1
                app.LocalCheckBox.Value = 0;
                level = graythresh(app.image_original);
                app.image_mooney = imbinarize(app.image_blur,level);
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                app.ThresholdSlider.Value = level;
           elseif string(app.ThresholdSwitch.Value) == "Yes" && value == 0
                app.image_mooney = imbinarize(app.image_blur);
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                
            end
        end

        % Value changed function: LocalCheckBox
        function LocalCheckBoxValueChanged(app, event)
            value = app.LocalCheckBox.Value;
            if string(app.ThresholdSwitch.Value) == "Yes" && value == 1
                app.OtsusCheckBox.Value = 0;
                level = adaptthresh(app.image_original);
                app.image_mooney = imbinarize(app.image_blur,level);
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                app.ThresholdSlider.Value = mean(level(:));
           elseif string(app.ThresholdSwitch.Value) == "Yes" && value == 0
                app.image_mooney = imbinarize(app.image_blur);
                imshow(app.image_mooney, 'Parent',app.UIAxes_2)
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Unambiguous image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Ambiguous image')
            app.BlurSwitchLabel = uilabel(app.UIFigure);
            app.BlurSwitchLabel.Text = 'Blur?';
            app.BlurSwitch = uiswitch(app.UIFigure, 'slider');
            app.BlurSwitch.Items = {'No', 'Yes'};
            app.BlurSwitch.ValueChangedFcn = createCallbackFcn(app, @BlurSwitchValueChanged, true);
            app.BlurSwitch.Value = 'Yes';
            app.ThresholdSwitchLabel = uilabel(app.UIFigure);
            app.ThresholdSwitchLabel.Text = 'Threshold?';
            app.ThresholdSwitch = uiswitch(app.UIFigure, 'slider');
            app.ThresholdSwitch.Items = {'No', 'Yes'};
            app.ThresholdSwitch.ValueChangedFcn = createCallbackFcn(app, @ThresholdSwitchValueChanged, true);
            app.ThresholdSwitch.Value = 'Yes';
            app.BlurlevelSliderLabel = uilabel(app.UIFigure);
            app.BlurlevelSliderLabel.Text = {'Blur'; 'level'};
            app.BlurlevelSlider = uislider(app.UIFigure);
            app.BlurlevelSlider.Limits = [0.1 10];
            app.BlurlevelSlider.ValueChangedFcn = createCallbackFcn(app, @BlurlevelSliderValueChanged, true);
            app.BlurlevelSlider.Value = 0.1;
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Value = 0.001;
            app.ChooseinputfolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseinputfolderButtonPushed, true);
            app.ChooseinputfolderButton.Text = 'Choose input folder';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ChooseoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseoutputfolderButtonPushed, true);
            app.ChooseoutputfolderButton.Text = 'Choose output folder';
            app.OtsusCheckBox = uicheckbox(app.UIFigure);
            app.OtsusCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtsusCheckBoxValueChanged, true);
            app.OtsusCheckBox.Text = 'Otsu''s';
            app.LocalCheckBox = uicheckbox(app.UIFigure);
            app.LocalCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocalCheckBoxValueChanged, true);
            app.LocalCheckBox.Text = 'Local';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mooney_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end